classdef Agilent_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        SetInstrumentButton      matlab.ui.control.Button
        OutputEditField          matlab.ui.control.NumericEditField
        OutputLabel              matlab.ui.control.Label
        QueryInstrumentButton    matlab.ui.control.Button
        InstrNameEditField       matlab.ui.control.EditField
        InstrNameEditFieldLabel  matlab.ui.control.Label
        OffsetVDCEditField       matlab.ui.control.NumericEditField
        OffsetVDCLabel           matlab.ui.control.Label
        Agilent33220ALabel       matlab.ui.control.Label
        AmplitudeVrmsEditField   matlab.ui.control.NumericEditField
        AmplitudeVrmsLabel       matlab.ui.control.Label
        PhaseEditField           matlab.ui.control.NumericEditField
        PhaseEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        instrumentName = '';
    end
    
    methods (Access = private)
        
        function queryResult = query33220(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentName ');']);
        end

        function set33220(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentName ',' num2str(value) ');']);
        end

        function set33220Amplitude(app,setFunc,amplitude, voltType)
            evalin('base',[setFunc '(' app.instrumentName ',' num2str(amplitude) ',' strcat('''' ,voltType, '''') ');']);
        end
    end
    
    methods (Access = public)
        
        function update33220App(app)
            app.PhaseEditField.Value            = query33220(app,'query33220Phase');
            app.AmplitudeVrmsEditField.Value    = query33220(app,'query33220Amplitude');
            app.OffsetVDCEditField.Value        = query33220(app,'query33220VoltageOffset');
            app.FrequencyEditField.Value        = query33220(app,'query33220Frequency');
            app.OutputEditField.Value           = query33220(app,'query33220OutputOnOff');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QueryInstrumentButton
        function QueryInstrumentButtonPushed(app, event)
            update33220App(app);
        end

        % Value changed function: InstrNameEditField
        function InstrNameEditFieldValueChanged(app, event)
            app.instrumentName = app.InstrNameEditField.Value;
        end

        % Button pushed function: SetInstrumentButton
        function SetInstrumentButtonPushed(app, event)
            set33220(app,'set33220Phase',app.PhaseEditField.Value);
            set33220Amplitude(app,'set33220Amplitude',app.AmplitudeVrmsEditField.Value, 'VRMS');
            set33220(app,'set33220VoltageOffset',app.OffsetVDCEditField.Value);
            set33220(app,'set33220Frequency',app.FrequencyEditField.Value);
            set33220(app,'set33220OutputOnOff',app.OutputEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeVrmsLabel = uilabel(app.UIFigure);
            app.AmplitudeVrmsLabel.Text = 'Amplitude (Vrms)';
            app.AmplitudeVrmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Agilent33220ALabel = uilabel(app.UIFigure);
            app.Agilent33220ALabel.Text = 'Agilent 33220A';
            app.OffsetVDCLabel = uilabel(app.UIFigure);
            app.OffsetVDCLabel.Text = 'Offset (VDC)';
            app.OffsetVDCEditField = uieditfield(app.UIFigure, 'numeric');
            app.InstrNameEditFieldLabel = uilabel(app.UIFigure);
            app.InstrNameEditFieldLabel.Text = 'Instr Name';
            app.InstrNameEditField = uieditfield(app.UIFigure, 'text');
            app.InstrNameEditField.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditFieldValueChanged, true);
            app.QueryInstrumentButton = uibutton(app.UIFigure, 'push');
            app.QueryInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButtonPushed, true);
            app.QueryInstrumentButton.Text = 'Query Instrument';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetInstrumentButton = uibutton(app.UIFigure, 'push');
            app.SetInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButtonPushed, true);
            app.SetInstrumentButton.Text = 'Set Instrument';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Agilent_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end