classdef HarvardDACGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        H7Label                  matlab.ui.control.Label
        H6Label                  matlab.ui.control.Label
        H5Label                  matlab.ui.control.Label
        H4Label                  matlab.ui.control.Label
        H3Label                  matlab.ui.control.Label
        H2Label                  matlab.ui.control.Label
        H1Label                  matlab.ui.control.Label
        H0Label                  matlab.ui.control.Label
        numChanEditField         matlab.ui.control.NumericEditField
        numChanEditFieldLabel    matlab.ui.control.Label
        Inst_NameEditField       matlab.ui.control.EditField
        Inst_NameEditFieldLabel  matlab.ui.control.Label
        EditField_8              matlab.ui.control.EditField
        EditField_7              matlab.ui.control.EditField
        EditField_6              matlab.ui.control.EditField
        EditField_5              matlab.ui.control.EditField
        EditField_4              matlab.ui.control.EditField
        EditField_3              matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EditField                matlab.ui.control.EditField
        UpdateAllButton          matlab.ui.control.Button
        HarvardDACLabel          matlab.ui.control.Label
        c8EditField              matlab.ui.control.NumericEditField
        c7EditField              matlab.ui.control.NumericEditField
        c6EditField              matlab.ui.control.NumericEditField
        c5EditField              matlab.ui.control.NumericEditField
        c4EditField              matlab.ui.control.NumericEditField
        c3EditField              matlab.ui.control.NumericEditField
        c2EditField              matlab.ui.control.NumericEditField
        c1EditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        instrumentName = ''; 
        numChannels = 8;
    end
    
    methods (Access = private)
        function fieldArr = getEditFields(app)
            fieldArr = [app.c1EditField,app.c2EditField,app.c3EditField,app.c4EditField,app.c5EditField,app.c6EditField,app.c7EditField,app.c8EditField]; % Description
        end

        function [] = updateEditField(app,channel,val)
            fieldArr = getEditFields(app);
            numChans = app.numChannels;
            if channel <= numChans 
                fieldArr(channel).Value = val;
            else
                disp('You do not have that many channels!');
            end
        end

        function queryDAC(app,channel)

            voltage = evalin('base',[app.instrumentName '.DACVoltage(' num2str(channel) ')']);
            mV = 1e-3; % turn unit back to V
            updateEditField(app,channel,voltage*mV);

        end
    end
    
    methods (Access = public)

        function [] = updateDACGUIChan(app,channel)
            queryDAC(app,channel);
        end

        function updateDACGUI(app)
        
            for i = 1:app.numChannels
                updateDACGUIChan(app,i);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)
            for i=1:app.numChannels
                queryDAC(app,i);
            end
        end

        % Callback function
        function Update18ButtonPushed(app, event)
            app.numChannels;
            if app.numChannels < 1
                disp('You do not have that many channels!');
            else
                for i=1:8
                    queryDAC(app,i);
                end
            end
            
        end

        % Callback function
        function Update916ButtonPushed(app, event)
            if app.numChannels < 9
                disp('You do not have that many channels!');
                return
            else
                for i=9:16
                    queryDAC(app,i);
                end
            end
            
        end

        % Callback function
        function Update1724ButtonPushed(app, event)
            if app.numChannels < 17
                disp('You do not have that many channels!');
            else
                for i=17:24
                    queryDAC(app,i);
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('Close the DAC GUI?',...
                'Close Request Function',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app);
                case 'No'
                    return
            end
            
        end

        % Value changed function: Inst_NameEditField
        function Inst_NameEditFieldValueChanged(app, event)
            value = app.Inst_NameEditField.Value;
            app.instrumentName = value;
        end

        % Value changed function: numChanEditField
        function numChanEditFieldValueChanged(app, event)
            app.numChannels = app.numChanEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.c1EditField = uieditfield(app.UIFigure, 'numeric');
            app.c2EditField = uieditfield(app.UIFigure, 'numeric');
            app.c3EditField = uieditfield(app.UIFigure, 'numeric');
            app.c4EditField = uieditfield(app.UIFigure, 'numeric');
            app.c5EditField = uieditfield(app.UIFigure, 'numeric');
            app.c6EditField = uieditfield(app.UIFigure, 'numeric');
            app.c7EditField = uieditfield(app.UIFigure, 'numeric');
            app.c8EditField = uieditfield(app.UIFigure, 'numeric');
            app.HarvardDACLabel = uilabel(app.UIFigure);
            app.HarvardDACLabel.Text = 'Harvard DAC';
            app.UpdateAllButton = uibutton(app.UIFigure, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Text = 'Update All';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Vc_1';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Vg_1';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Vg_2';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'Vf_1';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = 'Vf_2';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Value = 'Vc_2';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Value = 'BM';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.Value = 'Tf_E';
            app.Inst_NameEditFieldLabel = uilabel(app.UIFigure);
            app.Inst_NameEditFieldLabel.Text = 'Inst_Name';
            app.Inst_NameEditField = uieditfield(app.UIFigure, 'text');
            app.Inst_NameEditField.ValueChangedFcn = createCallbackFcn(app, @Inst_NameEditFieldValueChanged, true);
            app.Inst_NameEditField.Value = 'DAC name';
            app.numChanEditFieldLabel = uilabel(app.UIFigure);
            app.numChanEditFieldLabel.Text = 'numChan';
            app.numChanEditField = uieditfield(app.UIFigure, 'numeric');
            app.numChanEditField.ValueChangedFcn = createCallbackFcn(app, @numChanEditFieldValueChanged, true);
            app.H0Label = uilabel(app.UIFigure);
            app.H0Label.Text = 'H0';
            app.H1Label = uilabel(app.UIFigure);
            app.H1Label.Text = 'H1';
            app.H2Label = uilabel(app.UIFigure);
            app.H2Label.Text = 'H2';
            app.H3Label = uilabel(app.UIFigure);
            app.H3Label.Text = 'H3';
            app.H4Label = uilabel(app.UIFigure);
            app.H4Label.Text = 'H4';
            app.H5Label = uilabel(app.UIFigure);
            app.H5Label.Text = 'H5';
            app.H6Label = uilabel(app.UIFigure);
            app.H6Label.Text = 'H6';
            app.H7Label = uilabel(app.UIFigure);
            app.H7Label.Text = 'H7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarvardDACGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end