classdef SR830_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FrequencyEditField          matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        SetInstrumentButton         matlab.ui.control.Button
        Aux4EditField               matlab.ui.control.NumericEditField
        Aux4EditFieldLabel          matlab.ui.control.Label
        Aux3EditField               matlab.ui.control.NumericEditField
        Aux3EditFieldLabel          matlab.ui.control.Label
        Aux2EditField               matlab.ui.control.NumericEditField
        Aux2EditFieldLabel          matlab.ui.control.Label
        Aux1EditField               matlab.ui.control.NumericEditField
        Aux1EditFieldLabel          matlab.ui.control.Label
        QueryInstrumentButton       matlab.ui.control.Button
        InstrNameEditField          matlab.ui.control.EditField
        InstrNameEditFieldLabel     matlab.ui.control.Label
        TimeConstantEditField       matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField        matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel   matlab.ui.control.Label
        SR830Label                  matlab.ui.control.Label
        VoltageVEditField           matlab.ui.control.NumericEditField
        VoltageVEditFieldLabel      matlab.ui.control.Label
        QuadratureEditField         matlab.ui.control.NumericEditField
        QuadratureEditFieldLabel    matlab.ui.control.Label
        InPhaseEditField            matlab.ui.control.NumericEditField
        InPhaseEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        instrumentName = '';
    end
    
    methods (Access = private)
        
        function queryResult = querySR830(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentName ');']);
        end

        function queryChannelResult = querySR830Chan(app,queryFunc,channel)
            queryChannelResult = evalin('base',[queryFunc '(' app.instrumentName ',' num2str(channel) ');']);
        end

        function setSR830(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentName ',' num2str(value) ');']);
        end

        function setSR830Chan(app,setFunc,channel,value)
            evalin('base',[setFunc '(' app.instrumentName ',' num2str(channel) ',' num2str(value) ');']);
        end
    end
    
    methods (Access = public)
        
        function updateSR830App(app)
            app.InPhaseEditField.Value          = querySR830(app,'SR830queryX');
            app.QuadratureEditField.Value       = querySR830(app,'SR830queryY');
            app.FrequencyEditField.Value        = querySR830(app,'SR830queryFreq');
            app.VoltageVEditField.Value         = querySR830(app,'SR830queryAmplitude');
            app.SensitivityEditField.Value      = querySR830(app,'SR830querySensitivity');
            app.TimeConstantEditField.Value     = querySR830(app,'SR830queryTimeConstant');
            app.Aux1EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',1);
            app.Aux2EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',2);
            app.Aux3EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',3);
            app.Aux4EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QueryInstrumentButton
        function QueryInstrumentButtonPushed(app, event)
            updateSR830App(app);
        end

        % Value changed function: InstrNameEditField
        function InstrNameEditFieldValueChanged(app, event)
            app.instrumentName = app.InstrNameEditField.Value;
        end

        % Button pushed function: SetInstrumentButton
        function SetInstrumentButtonPushed(app, event)
            setSR830(app,'SR830setFreq',app.FrequencyEditField.Value);
            setSR830(app,'SR830setAmplitude',app.VoltageVEditField.Value);
            setSR830Chan(app,'SR830setAuxOut',1,app.Aux1EditField.Value);
            setSR830Chan(app,'SR830setAuxOut',2,app.Aux2EditField.Value);
            setSR830Chan(app,'SR830setAuxOut',3,app.Aux3EditField.Value);
            setSR830Chan(app,'SR830setAuxOut',4,app.Aux4EditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InPhaseEditFieldLabel = uilabel(app.UIFigure);
            app.InPhaseEditFieldLabel.Text = 'In-Phase';
            app.InPhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuadratureEditFieldLabel = uilabel(app.UIFigure);
            app.QuadratureEditFieldLabel.Text = 'Quadrature';
            app.QuadratureEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V)';
            app.VoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.SR830Label = uilabel(app.UIFigure);
            app.SR830Label.Text = 'SR830';
            app.SensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeConstantEditFieldLabel = uilabel(app.UIFigure);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.InstrNameEditFieldLabel = uilabel(app.UIFigure);
            app.InstrNameEditFieldLabel.Text = 'Instr Name';
            app.InstrNameEditField = uieditfield(app.UIFigure, 'text');
            app.InstrNameEditField.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditFieldValueChanged, true);
            app.QueryInstrumentButton = uibutton(app.UIFigure, 'push');
            app.QueryInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButtonPushed, true);
            app.QueryInstrumentButton.Text = 'Query Instrument';
            app.Aux1EditFieldLabel = uilabel(app.UIFigure);
            app.Aux1EditFieldLabel.Text = 'Aux 1';
            app.Aux1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Aux2EditFieldLabel = uilabel(app.UIFigure);
            app.Aux2EditFieldLabel.Text = 'Aux 2';
            app.Aux2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Aux3EditFieldLabel = uilabel(app.UIFigure);
            app.Aux3EditFieldLabel.Text = 'Aux 3';
            app.Aux3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Aux4EditFieldLabel = uilabel(app.UIFigure);
            app.Aux4EditFieldLabel.Text = 'Aux 4';
            app.Aux4EditField = uieditfield(app.UIFigure, 'numeric');
            app.SetInstrumentButton = uibutton(app.UIFigure, 'push');
            app.SetInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButtonPushed, true);
            app.SetInstrumentButton.Text = 'Set Instrument';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SR830_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end