classdef Tiffany_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        numChanEditField               matlab.ui.control.NumericEditField
        numChanEditFieldLabel          matlab.ui.control.Label
        Inst_NameEditField             matlab.ui.control.EditField
        Inst_NameEditFieldLabel        matlab.ui.control.Label
        EditField_24                   matlab.ui.control.EditField
        EditField_23                   matlab.ui.control.EditField
        EditField_22                   matlab.ui.control.EditField
        EditField_21                   matlab.ui.control.EditField
        EditField_20                   matlab.ui.control.EditField
        EditField_19                   matlab.ui.control.EditField
        EditField_18                   matlab.ui.control.EditField
        EditField_17                   matlab.ui.control.EditField
        EditField_16                   matlab.ui.control.EditField
        EditField_15                   matlab.ui.control.EditField
        EditField_14                   matlab.ui.control.EditField
        EditField_13                   matlab.ui.control.EditField
        EditField_12                   matlab.ui.control.EditField
        EditField_11                   matlab.ui.control.EditField
        EditField_10                   matlab.ui.control.EditField
        EditField_9                    matlab.ui.control.EditField
        EditField_8                    matlab.ui.control.EditField
        EditField_7                    matlab.ui.control.EditField
        EditField_6                    matlab.ui.control.EditField
        EditField_5                    matlab.ui.control.EditField
        EditField_4                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        Update1724Button               matlab.ui.control.Button
        Update916Button                matlab.ui.control.Button
        Update18Button                 matlab.ui.control.Button
        UpdateAllButton                matlab.ui.control.Button
        sigDACLabel                    matlab.ui.control.Label
        c24EditField                   matlab.ui.control.NumericEditField
        c23EditField                   matlab.ui.control.NumericEditField
        c22EditField                   matlab.ui.control.NumericEditField
        c21EditField                   matlab.ui.control.NumericEditField
        c20EditField                   matlab.ui.control.NumericEditField
        c19EditField                   matlab.ui.control.NumericEditField
        c18EditField                   matlab.ui.control.NumericEditField
        c17EditField                   matlab.ui.control.NumericEditField
        c16EditField                   matlab.ui.control.NumericEditField
        c15EditField                   matlab.ui.control.NumericEditField
        c14EditField                   matlab.ui.control.NumericEditField
        c13EditField                   matlab.ui.control.NumericEditField
        c12EditField                   matlab.ui.control.NumericEditField
        c11EditField                   matlab.ui.control.NumericEditField
        c10EditField                   matlab.ui.control.NumericEditField
        c9EditField                    matlab.ui.control.NumericEditField
        c8EditField                    matlab.ui.control.NumericEditField
        c7EditField                    matlab.ui.control.NumericEditField
        c6EditField                    matlab.ui.control.NumericEditField
        c5EditField                    matlab.ui.control.NumericEditField
        c4EditField                    matlab.ui.control.NumericEditField
        c3EditField                    matlab.ui.control.NumericEditField
        c2EditField                    matlab.ui.control.NumericEditField
        c1EditField                    matlab.ui.control.NumericEditField
        OutputEditField_4              matlab.ui.control.NumericEditField
        OutputEditField_4Label         matlab.ui.control.Label
        InstrNameEditField_4           matlab.ui.control.EditField
        InstrNameEditField_4Label      matlab.ui.control.Label
        OffsetVDCEditField_4           matlab.ui.control.NumericEditField
        OffsetVDCEditField_4Label      matlab.ui.control.Label
        AmplitudeVrmsEditField_4       matlab.ui.control.NumericEditField
        AmplitudeVrmsEditField_4Label  matlab.ui.control.Label
        PhaseEditField_4               matlab.ui.control.NumericEditField
        PhaseEditField_4Label          matlab.ui.control.Label
        OutputEditField_3              matlab.ui.control.NumericEditField
        OutputEditField_3Label         matlab.ui.control.Label
        InstrNameEditField_3           matlab.ui.control.EditField
        InstrNameEditField_3Label      matlab.ui.control.Label
        OffsetVDCEditField_3           matlab.ui.control.NumericEditField
        OffsetVDCEditField_3Label      matlab.ui.control.Label
        AmplitudeVrmsEditField_3       matlab.ui.control.NumericEditField
        AmplitudeVrmsEditField_3Label  matlab.ui.control.Label
        PhaseEditField_3               matlab.ui.control.NumericEditField
        PhaseEditField_3Label          matlab.ui.control.Label
        OutputEditField_2              matlab.ui.control.NumericEditField
        OutputEditField_2Label         matlab.ui.control.Label
        InstrNameEditField_2           matlab.ui.control.EditField
        InstrNameEditField_2Label      matlab.ui.control.Label
        OffsetVDCEditField_2           matlab.ui.control.NumericEditField
        OffsetVDCEditField_2Label      matlab.ui.control.Label
        AmplitudeVrmsEditField_2       matlab.ui.control.NumericEditField
        AmplitudeVrmsEditField_2Label  matlab.ui.control.Label
        PhaseEditField_2               matlab.ui.control.NumericEditField
        PhaseEditField_2Label          matlab.ui.control.Label
        OutputEditField                matlab.ui.control.NumericEditField
        OutputEditFieldLabel           matlab.ui.control.Label
        InstrNameEditField             matlab.ui.control.EditField
        InstrNameEditFieldLabel        matlab.ui.control.Label
        OffsetVDCEditField             matlab.ui.control.NumericEditField
        OffsetVDCEditFieldLabel        matlab.ui.control.Label
        AmplitudeVrmsEditField         matlab.ui.control.NumericEditField
        AmplitudeVrmsEditFieldLabel    matlab.ui.control.Label
        PhaseEditField                 matlab.ui.control.NumericEditField
        PhaseEditFieldLabel            matlab.ui.control.Label
        FrequencyEditField_3           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_3      matlab.ui.control.Label
        SetInstrumentButton_4          matlab.ui.control.Button
        QueryInstrumentButton_4        matlab.ui.control.Button
        FrequencyEditField_2           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_2      matlab.ui.control.Label
        SetInstrumentButton_3          matlab.ui.control.Button
        QueryInstrumentButton_3        matlab.ui.control.Button
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        SetInstrumentButton_2          matlab.ui.control.Button
        QueryInstrumentButton_2        matlab.ui.control.Button
        SetInstrumentButton            matlab.ui.control.Button
        QueryInstrumentButton          matlab.ui.control.Button
        Agilent33220ALabel             matlab.ui.control.Label
        EmitterDoorPanel               matlab.ui.container.Panel
        CollectorTwiddlePanel          matlab.ui.container.Panel
        FrequencyEditField_4           matlab.ui.control.NumericEditField
        FrequencyEditField_4Label      matlab.ui.control.Label
        CollectorDoorPanel             matlab.ui.container.Panel
        EmitterTwiddlePanel            matlab.ui.container.Panel
        Tab2                           matlab.ui.container.Tab
        FrequencyEditField_6           matlab.ui.control.NumericEditField
        FrequencyEditField_6Label      matlab.ui.control.Label
        Aux4EditField_2                matlab.ui.control.NumericEditField
        Aux4EditField_2Label           matlab.ui.control.Label
        Aux3EditField_2                matlab.ui.control.NumericEditField
        Aux3EditField_2Label           matlab.ui.control.Label
        Aux2EditField_2                matlab.ui.control.NumericEditField
        Aux2EditField_2Label           matlab.ui.control.Label
        Aux1EditField_2                matlab.ui.control.NumericEditField
        Aux1EditField_2Label           matlab.ui.control.Label
        InstrNameEditField_6           matlab.ui.control.EditField
        InstrNameEditField_6Label      matlab.ui.control.Label
        TimeConstantEditField_2        matlab.ui.control.NumericEditField
        TimeConstantEditField_2Label   matlab.ui.control.Label
        SensitivityEditField_2         matlab.ui.control.NumericEditField
        SensitivityEditField_2Label    matlab.ui.control.Label
        VoltageVEditField_2            matlab.ui.control.NumericEditField
        VoltageVEditField_2Label       matlab.ui.control.Label
        QuadratureEditField_2          matlab.ui.control.NumericEditField
        QuadratureEditField_2Label     matlab.ui.control.Label
        InPhaseEditField_2             matlab.ui.control.NumericEditField
        InPhaseEditField_2Label        matlab.ui.control.Label
        FrequencyEditField_5           matlab.ui.control.NumericEditField
        FrequencyEditField_5Label      matlab.ui.control.Label
        Aux4EditField                  matlab.ui.control.NumericEditField
        Aux4EditFieldLabel             matlab.ui.control.Label
        Aux3EditField                  matlab.ui.control.NumericEditField
        Aux3EditFieldLabel             matlab.ui.control.Label
        Aux2EditField                  matlab.ui.control.NumericEditField
        Aux2EditFieldLabel             matlab.ui.control.Label
        Aux1EditField                  matlab.ui.control.NumericEditField
        Aux1EditFieldLabel             matlab.ui.control.Label
        InstrNameEditField_5           matlab.ui.control.EditField
        InstrNameEditField_5Label      matlab.ui.control.Label
        TimeConstantEditField          matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel     matlab.ui.control.Label
        SensitivityEditField           matlab.ui.control.NumericEditField
        SensitivityEditFieldLabel      matlab.ui.control.Label
        VoltageVEditField              matlab.ui.control.NumericEditField
        VoltageVEditFieldLabel         matlab.ui.control.Label
        QuadratureEditField            matlab.ui.control.NumericEditField
        QuadratureEditFieldLabel       matlab.ui.control.Label
        InPhaseEditField               matlab.ui.control.NumericEditField
        InPhaseEditFieldLabel          matlab.ui.control.Label
        SetInstrumentButton_6          matlab.ui.control.Button
        QueryInstrumentButton_6        matlab.ui.control.Button
        SR830Label_2                   matlab.ui.control.Label
        SetInstrumentButton_5          matlab.ui.control.Button
        QueryInstrumentButton_5        matlab.ui.control.Button
        SR830Label                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        instrumentName = '';
        instrumentName_2 = '';
        instrumentName_3 = '';
        instrumentName_4 = '';
        instrumentNameSR830 = '';
        instrumentNameSR830_2 = '';
        instrumentNameDAC = ''; 
        numChannels = 8;
    end
    
    methods (Access = private)
        
        function queryResult = query33220(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentName ');']);
        end

        function set33220(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentName ',' num2str(value) ');']);
        end

        function set33220Amplitude(app,setFunc,amplitude, voltType)
            evalin('base',[setFunc '(' app.instrumentName ',' num2str(amplitude) ',' strcat('''' ,voltType, '''') ');']);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function queryResult = query33220_2(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentName_2 ');']);
        end

        function set33220_2(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentName_2 ',' num2str(value) ');']);
        end

        function set33220Amplitude_2(app,setFunc,amplitude, voltType)
            evalin('base',[setFunc '(' app.instrumentName_2 ',' num2str(amplitude) ',' strcat('''' ,voltType, '''') ');']);
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function queryResult = query33220_3(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentName_3 ');']);
        end

        function set33220_3(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentName_3 ',' num2str(value) ');']);
        end

        function set33220Amplitude_3(app,setFunc,amplitude, voltType)
            evalin('base',[setFunc '(' app.instrumentName_3 ',' num2str(amplitude) ',' strcat('''' ,voltType, '''') ');']);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function queryResult = query33220_4(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentName_4 ');']);
        end

        function set33220_4(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentName_4 ',' num2str(value) ');']);
        end

        function set33220Amplitude_4(app,setFunc,amplitude, voltType)
            evalin('base',[setFunc '(' app.instrumentName_4 ',' num2str(amplitude) ',' strcat('''' ,voltType, '''') ');']);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function queryResult = querySR830(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentNameSR830 ');']);
        end

        function queryChannelResult = querySR830Chan(app,queryFunc,channel)
            queryChannelResult = evalin('base',[queryFunc '(' app.instrumentNameSR830 ',' num2str(channel) ');']);
        end

        function setSR830(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentNameSR830 ',' num2str(value) ');']);
        end

        function setSR830Chan(app,setFunc,channel,value)
            evalin('base',[setFunc '(' app.instrumentNameSR830 ',' num2str(channel) ',' num2str(value) ');']);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function queryResult = querySR830_2(app,queryFunc)
            queryResult = evalin('base',[queryFunc '(' app.instrumentNameSR830_2 ');']);
        end

        function queryChannelResult = querySR830Chan_2(app,queryFunc,channel)
            queryChannelResult = evalin('base',[queryFunc '(' app.instrumentNameSR830_2 ',' num2str(channel) ');']);
        end

        function setSR830_2(app,setFunc,value)
            evalin('base',[setFunc '(' app.instrumentNameSR830_2 ',' num2str(value) ');']);
        end

        function setSR830Chan_2(app,setFunc,channel,value)
            evalin('base',[setFunc '(' app.instrumentNameSR830_2 ',' num2str(channel) ',' num2str(value) ');']);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function fieldArr = getEditFields(app)
            fieldArr = [app.c1EditField,app.c2EditField,app.c3EditField,app.c4EditField,app.c5EditField,app.c6EditField,app.c7EditField,app.c8EditField,app.c9EditField,app.c10EditField,app.c11EditField,app.c12EditField,app.c13EditField,app.c14EditField,app.c15EditField,app.c16EditField,app.c17EditField,app.c18EditField,app.c19EditField,app.c20EditField,app.c21EditField,app.c22EditField,app.c23EditField,app.c24EditField]; % Description
        end

        function [] = updateEditField(app,channel,val)
            fieldArr = getEditFields(app);
            numChans = app.numChannels;
            if channel <= numChans 
                fieldArr(channel).Value = val;
            else
                disp('You do not have that many channels!');
            end
        end

        function queryDAC(app,channel)
            voltage = evalin('base',['sigDACQueryVoltage(' app.instrumentNameDAC ',' num2str(channel) ');']);
            updateEditField(app,channel,voltage);
        end
      
    end
    
    methods (Access = public)
        
        function update33220App(app)
            app.PhaseEditField.Value            = query33220(app,'query33220Phase');
            app.AmplitudeVrmsEditField.Value    = query33220(app,'query33220Amplitude');
            app.OffsetVDCEditField.Value        = query33220(app,'query33220VoltageOffset');
            app.FrequencyEditField.Value        = query33220(app,'query33220Frequency');
            app.OutputEditField.Value           = query33220(app,'query33220OutputOnOff');
        end

        function update33220App_2(app)
            app.PhaseEditField_2.Value            = query33220_2(app,'query33220Phase');
            app.AmplitudeVrmsEditField_2.Value    = query33220_2(app,'query33220Amplitude');
            app.OffsetVDCEditField_2.Value        = query33220_2(app,'query33220VoltageOffset');
            app.FrequencyEditField_2.Value        = query33220_2(app,'query33220Frequency');
            app.OutputEditField_2.Value           = query33220_2(app,'query33220OutputOnOff');
        end

        function update33220App_3(app)
            app.PhaseEditField_3.Value            = query33220_3(app,'query33220Phase');
            app.AmplitudeVrmsEditField_3.Value    = query33220_3(app,'query33220Amplitude');
            app.OffsetVDCEditField_3.Value        = query33220_3(app,'query33220VoltageOffset');
            app.FrequencyEditField_3.Value        = query33220_3(app,'query33220Frequency');
            app.OutputEditField_3.Value           = query33220_3(app,'query33220OutputOnOff');
        end

        function update33220App_4(app)
            app.PhaseEditField_4.Value            = query33220_4(app,'query33220Phase');
            app.AmplitudeVrmsEditField_4.Value    = query33220_4(app,'query33220Amplitude');
            app.OffsetVDCEditField_4.Value        = query33220_4(app,'query33220VoltageOffset');
            app.FrequencyEditField_4.Value        = query33220_4(app,'query33220Frequency');
            app.OutputEditField_4.Value           = query33220_4(app,'query33220OutputOnOff');
        end

        function updateSR830App(app)
            app.InPhaseEditField.Value          = querySR830(app,'SR830queryX');
            app.QuadratureEditField.Value       = querySR830(app,'SR830queryY');
            app.FrequencyEditField_5.Value      = querySR830(app,'SR830queryFreq');
            app.VoltageVEditField.Value         = querySR830(app,'SR830queryAmplitude');
            app.SensitivityEditField.Value      = querySR830(app,'SR830querySensitivity');
            app.TimeConstantEditField.Value     = querySR830(app,'SR830queryTimeConstant');
            app.Aux1EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',1);
            app.Aux2EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',2);
            app.Aux3EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',3);
            app.Aux4EditField.Value             = querySR830Chan(app,'SR830queryAuxOut',4);
        end

        function updateSR830App_2(app)
            app.InPhaseEditField_2.Value          = querySR830_2(app,'SR830queryX');
            app.QuadratureEditField_2.Value       = querySR830_2(app,'SR830queryY');
            app.FrequencyEditField_6.Value        = querySR830_2(app,'SR830queryFreq');
            app.VoltageVEditField_2.Value         = querySR830_2(app,'SR830queryAmplitude');
            app.SensitivityEditField_2.Value      = querySR830_2(app,'SR830querySensitivity');
            app.TimeConstantEditField_2.Value     = querySR830_2(app,'SR830queryTimeConstant');
            app.Aux1EditField_2.Value             = querySR830Chan_2(app,'SR830queryAuxOut',1);
            app.Aux2EditField_2.Value             = querySR830Chan_2(app,'SR830queryAuxOut',2);
            app.Aux3EditField_2.Value             = querySR830Chan_2(app,'SR830queryAuxOut',3);
            app.Aux4EditField_2.Value             = querySR830Chan_2(app,'SR830queryAuxOut',4);
        end

        function [] = updateDACGUIChan(app,channel)
            queryDAC(app,channel);
        end

        function updateDACGUI(app)
            for i = 1:app.numChannels
                updateDACGUIChan(app,i);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QueryInstrumentButton
        function QueryInstrumentButtonPushed(app, event)
            update33220App(app);
        end

        % Value changed function: InstrNameEditField
        function InstrNameEditFieldValueChanged(app, event)
            app.instrumentName = app.InstrNameEditField.Value;
        end

        % Button pushed function: SetInstrumentButton
        function SetInstrumentButtonPushed(app, event)
            set33220(app,'set33220Phase',app.PhaseEditField.Value);
            set33220Amplitude(app,'set33220Amplitude',app.AmplitudeVrmsEditField.Value, 'VRMS');
            set33220(app,'set33220VoltageOffset',app.OffsetVDCEditField.Value);
            set33220(app,'set33220Frequency',app.FrequencyEditField.Value);
            set33220(app,'set33220OutputOnOff',app.OutputEditField.Value);
        end

        % Button pushed function: QueryInstrumentButton_2
        function QueryInstrumentButton_2Pushed(app, event)
             update33220App_2(app);
        end

        % Value changed function: InstrNameEditField_2
        function InstrNameEditField_2ValueChanged(app, event)
            app.instrumentName_2 = app.InstrNameEditField_2.Value;
        end

        % Button pushed function: SetInstrumentButton_2
        function SetInstrumentButton_2Pushed(app, event)
            set33220_2(app,'set33220Phase',app.PhaseEditField_2.Value);
            set33220Amplitude_2(app,'set33220Amplitude',app.AmplitudeVrmsEditField_2.Value, 'VRMS');
            set33220_2(app,'set33220VoltageOffset',app.OffsetVDCEditField_2.Value);
            set33220_2(app,'set33220Frequency',app.FrequencyEditField_2.Value);
            set33220_2(app,'set33220OutputOnOff',app.OutputEditField_2.Value);
        end

        % Button pushed function: QueryInstrumentButton_3
        function QueryInstrumentButton_3Pushed(app, event)
            update33220App_3(app);
        end

        % Value changed function: InstrNameEditField_3
        function InstrNameEditField_3ValueChanged(app, event)
            app.instrumentName_3 = app.InstrNameEditField_3.Value;
        end

        % Button pushed function: SetInstrumentButton_3
        function SetInstrumentButton_3Pushed(app, event)
            set33220_3(app,'set33220Phase',app.PhaseEditField_3.Value);
            set33220Amplitude_3(app,'set33220Amplitude',app.AmplitudeVrmsEditField_3.Value, 'VRMS');
            set33220_3(app,'set33220VoltageOffset',app.OffsetVDCEditField_3.Value);
            set33220_3(app,'set33220Frequency',app.FrequencyEditField_3.Value);
            set33220_3(app,'set33220OutputOnOff',app.OutputEditField_3.Value);
        end

        % Button pushed function: QueryInstrumentButton_4
        function QueryInstrumentButton_4Pushed(app, event)
            update33220App_4(app);
        end

        % Value changed function: InstrNameEditField_4
        function InstrNameEditField_4ValueChanged(app, event)
            app.instrumentName_4 = app.InstrNameEditField_4.Value;
        end

        % Button pushed function: SetInstrumentButton_4
        function SetInstrumentButton_4Pushed(app, event)
            set33220_4(app,'set33220Phase',app.PhaseEditField_4.Value);
            set33220Amplitude_4(app,'set33220Amplitude',app.AmplitudeVrmsEditField_4.Value, 'VRMS');
            set33220_4(app,'set33220VoltageOffset',app.OffsetVDCEditField_4.Value);
            set33220_4(app,'set33220Frequency',app.FrequencyEditField_4.Value);
            set33220_4(app,'set33220OutputOnOff',app.OutputEditField_4.Value);
        end

        % Button pushed function: QueryInstrumentButton_5
        function QueryInstrumentButton_5Pushed(app, event)
            updateSR830App(app);
        end

        % Value changed function: InstrNameEditField_5
        function InstrNameEditField_5ValueChanged(app, event)
            app.instrumentNameSR830 = app.InstrNameEditField_5.Value;
        end

        % Button pushed function: SetInstrumentButton_5
        function SetInstrumentButton_5Pushed(app, event)
            setSR830(app,'SR830setFreq',app.FrequencyEditField_5.Value);
            setSR830(app,'SR830setAmplitude',app.VoltageVEditField.Value);
            setSR830Chan(app,'SR830setAuxOut',1,app.Aux1EditField.Value);
            setSR830Chan(app,'SR830setAuxOut',2,app.Aux2EditField.Value);
            setSR830Chan(app,'SR830setAuxOut',3,app.Aux3EditField.Value);
            setSR830Chan(app,'SR830setAuxOut',4,app.Aux4EditField.Value);
        end

        % Button pushed function: QueryInstrumentButton_6
        function QueryInstrumentButton_6Pushed(app, event)
            updateSR830App_2(app);
        end

        % Value changed function: InstrNameEditField_6
        function InstrNameEditField_6ValueChanged(app, event)
            app.instrumentNameSR830_2 = app.InstrNameEditField_6.Value;
        end

        % Button pushed function: SetInstrumentButton_6
        function SetInstrumentButton_6Pushed(app, event)
            setSR830_2(app,'SR830setFreq',app.FrequencyEditField_6.Value);
            setSR830_2(app,'SR830setAmplitude',app.VoltageVEditField_2.Value);
            setSR830Chan_2(app,'SR830setAuxOut',1,app.Aux1EditField_2.Value);
            setSR830Chan_2(app,'SR830setAuxOut',2,app.Aux2EditField_2.Value);
            setSR830Chan_2(app,'SR830setAuxOut',3,app.Aux3EditField_2.Value);
            setSR830Chan_2(app,'SR830setAuxOut',4,app.Aux4EditField_2.Value);
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)
            for i=1:app.numChannels
                queryDAC(app,i);
            end
        end

        % Button pushed function: Update18Button
        function Update18ButtonPushed(app, event)
            app.numChannels;
            if app.numChannels < 1
                disp('You do not have that many channels!');
            else
                for i=1:8
                    queryDAC(app,i);
                end
            end
        end

        % Button pushed function: Update916Button
        function Update916ButtonPushed(app, event)
            if app.numChannels < 9
                disp('You do not have that many channels!');
                return
            else
                for i=9:16
                    queryDAC(app,i);
                end
            end
        end

        % Button pushed function: Update1724Button
        function Update1724ButtonPushed(app, event)
            if app.numChannels < 17
                disp('You do not have that many channels!');
            else
                for i=17:24
                    queryDAC(app,i);
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('Close the DAC GUI?',...
                'Close Request Function',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app);
                case 'No'
                    return
            end
        end

        % Value changed function: Inst_NameEditField
        function Inst_NameEditFieldValueChanged(app, event)
            value = app.Inst_NameEditField.Value;
            app.instrumentNameDAC = value;
        end

        % Value changed function: numChanEditField
        function numChanEditFieldValueChanged(app, event)
            app.numChannels = app.numChanEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.EmitterTwiddlePanel = uipanel(app.Tab);
            app.EmitterTwiddlePanel.Title = 'Emitter:Twiddle';
            app.CollectorDoorPanel = uipanel(app.Tab);
            app.CollectorDoorPanel.Title = 'Collector: Door';
            app.CollectorTwiddlePanel = uipanel(app.Tab);
            app.CollectorTwiddlePanel.Title = 'Collector: Twiddle';
            app.FrequencyEditField_4Label = uilabel(app.CollectorTwiddlePanel);
            app.FrequencyEditField_4Label.Text = 'Frequency';
            app.FrequencyEditField_4 = uieditfield(app.CollectorTwiddlePanel, 'numeric');
            app.EmitterDoorPanel = uipanel(app.Tab);
            app.EmitterDoorPanel.Title = 'Emitter: Door';
            app.Agilent33220ALabel = uilabel(app.Tab);
            app.Agilent33220ALabel.Text = 'Agilent 33220A';
            app.QueryInstrumentButton = uibutton(app.Tab, 'push');
            app.QueryInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButtonPushed, true);
            app.QueryInstrumentButton.Text = 'Query Instrument';
            app.SetInstrumentButton = uibutton(app.Tab, 'push');
            app.SetInstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButtonPushed, true);
            app.SetInstrumentButton.Text = 'Set Instrument';
            app.QueryInstrumentButton_2 = uibutton(app.Tab, 'push');
            app.QueryInstrumentButton_2.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButton_2Pushed, true);
            app.QueryInstrumentButton_2.Text = 'Query Instrument';
            app.SetInstrumentButton_2 = uibutton(app.Tab, 'push');
            app.SetInstrumentButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButton_2Pushed, true);
            app.SetInstrumentButton_2.Text = 'Set Instrument';
            app.FrequencyEditFieldLabel = uilabel(app.Tab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.Tab, 'numeric');
            app.QueryInstrumentButton_3 = uibutton(app.Tab, 'push');
            app.QueryInstrumentButton_3.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButton_3Pushed, true);
            app.QueryInstrumentButton_3.Text = 'Query Instrument';
            app.SetInstrumentButton_3 = uibutton(app.Tab, 'push');
            app.SetInstrumentButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButton_3Pushed, true);
            app.SetInstrumentButton_3.Text = 'Set Instrument';
            app.FrequencyEditFieldLabel_2 = uilabel(app.Tab);
            app.FrequencyEditFieldLabel_2.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.Tab, 'numeric');
            app.QueryInstrumentButton_4 = uibutton(app.Tab, 'push');
            app.QueryInstrumentButton_4.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButton_4Pushed, true);
            app.QueryInstrumentButton_4.Text = 'Query Instrument';
            app.SetInstrumentButton_4 = uibutton(app.Tab, 'push');
            app.SetInstrumentButton_4.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButton_4Pushed, true);
            app.SetInstrumentButton_4.Text = 'Set Instrument';
            app.FrequencyEditFieldLabel_3 = uilabel(app.Tab);
            app.FrequencyEditFieldLabel_3.Text = 'Frequency';
            app.FrequencyEditField_3 = uieditfield(app.Tab, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.Tab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.Tab, 'numeric');
            app.AmplitudeVrmsEditFieldLabel = uilabel(app.Tab);
            app.AmplitudeVrmsEditFieldLabel.Text = 'Amplitude (Vrms)';
            app.AmplitudeVrmsEditField = uieditfield(app.Tab, 'numeric');
            app.OffsetVDCEditFieldLabel = uilabel(app.Tab);
            app.OffsetVDCEditFieldLabel.Text = 'Offset (VDC)';
            app.OffsetVDCEditField = uieditfield(app.Tab, 'numeric');
            app.InstrNameEditFieldLabel = uilabel(app.Tab);
            app.InstrNameEditFieldLabel.Text = 'Instr Name';
            app.InstrNameEditField = uieditfield(app.Tab, 'text');
            app.InstrNameEditField.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditFieldValueChanged, true);
            app.OutputEditFieldLabel = uilabel(app.Tab);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.Tab, 'numeric');
            app.PhaseEditField_2Label = uilabel(app.Tab);
            app.PhaseEditField_2Label.Text = 'Phase';
            app.PhaseEditField_2 = uieditfield(app.Tab, 'numeric');
            app.AmplitudeVrmsEditField_2Label = uilabel(app.Tab);
            app.AmplitudeVrmsEditField_2Label.Text = 'Amplitude (Vrms)';
            app.AmplitudeVrmsEditField_2 = uieditfield(app.Tab, 'numeric');
            app.OffsetVDCEditField_2Label = uilabel(app.Tab);
            app.OffsetVDCEditField_2Label.Text = 'Offset (VDC)';
            app.OffsetVDCEditField_2 = uieditfield(app.Tab, 'numeric');
            app.InstrNameEditField_2Label = uilabel(app.Tab);
            app.InstrNameEditField_2Label.Text = 'Instr Name';
            app.InstrNameEditField_2 = uieditfield(app.Tab, 'text');
            app.InstrNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditField_2ValueChanged, true);
            app.OutputEditField_2Label = uilabel(app.Tab);
            app.OutputEditField_2Label.Text = 'Output';
            app.OutputEditField_2 = uieditfield(app.Tab, 'numeric');
            app.PhaseEditField_3Label = uilabel(app.Tab);
            app.PhaseEditField_3Label.Text = 'Phase';
            app.PhaseEditField_3 = uieditfield(app.Tab, 'numeric');
            app.AmplitudeVrmsEditField_3Label = uilabel(app.Tab);
            app.AmplitudeVrmsEditField_3Label.Text = 'Amplitude (Vrms)';
            app.AmplitudeVrmsEditField_3 = uieditfield(app.Tab, 'numeric');
            app.OffsetVDCEditField_3Label = uilabel(app.Tab);
            app.OffsetVDCEditField_3Label.Text = 'Offset (VDC)';
            app.OffsetVDCEditField_3 = uieditfield(app.Tab, 'numeric');
            app.InstrNameEditField_3Label = uilabel(app.Tab);
            app.InstrNameEditField_3Label.Text = 'Instr Name';
            app.InstrNameEditField_3 = uieditfield(app.Tab, 'text');
            app.InstrNameEditField_3.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditField_3ValueChanged, true);
            app.OutputEditField_3Label = uilabel(app.Tab);
            app.OutputEditField_3Label.Text = 'Output';
            app.OutputEditField_3 = uieditfield(app.Tab, 'numeric');
            app.PhaseEditField_4Label = uilabel(app.Tab);
            app.PhaseEditField_4Label.Text = 'Phase';
            app.PhaseEditField_4 = uieditfield(app.Tab, 'numeric');
            app.AmplitudeVrmsEditField_4Label = uilabel(app.Tab);
            app.AmplitudeVrmsEditField_4Label.Text = 'Amplitude (Vrms)';
            app.AmplitudeVrmsEditField_4 = uieditfield(app.Tab, 'numeric');
            app.OffsetVDCEditField_4Label = uilabel(app.Tab);
            app.OffsetVDCEditField_4Label.Text = 'Offset (VDC)';
            app.OffsetVDCEditField_4 = uieditfield(app.Tab, 'numeric');
            app.InstrNameEditField_4Label = uilabel(app.Tab);
            app.InstrNameEditField_4Label.Text = 'Instr Name';
            app.InstrNameEditField_4 = uieditfield(app.Tab, 'text');
            app.InstrNameEditField_4.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditField_4ValueChanged, true);
            app.OutputEditField_4Label = uilabel(app.Tab);
            app.OutputEditField_4Label.Text = 'Output';
            app.OutputEditField_4 = uieditfield(app.Tab, 'numeric');
            app.c1EditField = uieditfield(app.Tab, 'numeric');
            app.c2EditField = uieditfield(app.Tab, 'numeric');
            app.c3EditField = uieditfield(app.Tab, 'numeric');
            app.c4EditField = uieditfield(app.Tab, 'numeric');
            app.c5EditField = uieditfield(app.Tab, 'numeric');
            app.c6EditField = uieditfield(app.Tab, 'numeric');
            app.c7EditField = uieditfield(app.Tab, 'numeric');
            app.c8EditField = uieditfield(app.Tab, 'numeric');
            app.c9EditField = uieditfield(app.Tab, 'numeric');
            app.c10EditField = uieditfield(app.Tab, 'numeric');
            app.c11EditField = uieditfield(app.Tab, 'numeric');
            app.c12EditField = uieditfield(app.Tab, 'numeric');
            app.c13EditField = uieditfield(app.Tab, 'numeric');
            app.c14EditField = uieditfield(app.Tab, 'numeric');
            app.c15EditField = uieditfield(app.Tab, 'numeric');
            app.c16EditField = uieditfield(app.Tab, 'numeric');
            app.c17EditField = uieditfield(app.Tab, 'numeric');
            app.c18EditField = uieditfield(app.Tab, 'numeric');
            app.c19EditField = uieditfield(app.Tab, 'numeric');
            app.c20EditField = uieditfield(app.Tab, 'numeric');
            app.c21EditField = uieditfield(app.Tab, 'numeric');
            app.c22EditField = uieditfield(app.Tab, 'numeric');
            app.c23EditField = uieditfield(app.Tab, 'numeric');
            app.c24EditField = uieditfield(app.Tab, 'numeric');
            app.sigDACLabel = uilabel(app.Tab);
            app.sigDACLabel.Text = 'sigDAC';
            app.UpdateAllButton = uibutton(app.Tab, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Text = 'Update All';
            app.Update18Button = uibutton(app.Tab, 'push');
            app.Update18Button.ButtonPushedFcn = createCallbackFcn(app, @Update18ButtonPushed, true);
            app.Update18Button.Text = 'Update 1-8';
            app.Update916Button = uibutton(app.Tab, 'push');
            app.Update916Button.ButtonPushedFcn = createCallbackFcn(app, @Update916ButtonPushed, true);
            app.Update916Button.Text = 'Update 9-16';
            app.Update1724Button = uibutton(app.Tab, 'push');
            app.Update1724Button.ButtonPushedFcn = createCallbackFcn(app, @Update1724ButtonPushed, true);
            app.Update1724Button.Text = 'Update 17-24';
            app.EditField = uieditfield(app.Tab, 'text');
            app.EditField.Value = 'STI C';
            app.EditField_2 = uieditfield(app.Tab, 'text');
            app.EditField_2.Value = 'TF Emitter';
            app.EditField_3 = uieditfield(app.Tab, 'text');
            app.EditField_3.Value = 'STI Emitter';
            app.EditField_4 = uieditfield(app.Tab, 'text');
            app.EditField_4.Value = 'TF Collector';
            app.EditField_5 = uieditfield(app.Tab, 'text');
            app.EditField_5.Value = 'NA';
            app.EditField_6 = uieditfield(app.Tab, 'text');
            app.EditField_6.Value = 'Top Fil Metal';
            app.EditField_7 = uieditfield(app.Tab, 'text');
            app.EditField_7.Value = 'IDC Neg --';
            app.EditField_8 = uieditfield(app.Tab, 'text');
            app.EditField_8.Value = 'Twiddle C';
            app.EditField_9 = uieditfield(app.Tab, 'text');
            app.EditField_9.Value = 'Door In C';
            app.EditField_10 = uieditfield(app.Tab, 'text');
            app.EditField_10.Value = 'Door In Emit';
            app.EditField_11 = uieditfield(app.Tab, 'text');
            app.EditField_11.Value = 'IDC Pos +';
            app.EditField_12 = uieditfield(app.Tab, 'text');
            app.EditField_12.Value = 'Sense C';
            app.EditField_13 = uieditfield(app.Tab, 'text');
            app.EditField_13.Value = 'Top E';
            app.EditField_14 = uieditfield(app.Tab, 'text');
            app.EditField_14.Value = 'Door C Open';
            app.EditField_15 = uieditfield(app.Tab, 'text');
            app.EditField_15.Value = 'Sense E';
            app.EditField_16 = uieditfield(app.Tab, 'text');
            app.EditField_16.Value = 'Door E Open';
            app.EditField_17 = uieditfield(app.Tab, 'text');
            app.EditField_17.Value = 'Top C';
            app.EditField_18 = uieditfield(app.Tab, 'text');
            app.EditField_18.Value = 'Twiddle E';
            app.EditField_19 = uieditfield(app.Tab, 'text');
            app.EditField_19.Value = 'STM C';
            app.EditField_20 = uieditfield(app.Tab, 'text');
            app.EditField_20.Value = 'STM E';
            app.EditField_21 = uieditfield(app.Tab, 'text');
            app.EditField_21.Value = 'STO C';
            app.EditField_22 = uieditfield(app.Tab, 'text');
            app.EditField_22.Value = 'Door C Close';
            app.EditField_23 = uieditfield(app.Tab, 'text');
            app.EditField_23.Value = 'STO E';
            app.EditField_24 = uieditfield(app.Tab, 'text');
            app.EditField_24.Value = 'Door E Close';
            app.Inst_NameEditFieldLabel = uilabel(app.Tab);
            app.Inst_NameEditFieldLabel.Text = 'Inst_Name';
            app.Inst_NameEditField = uieditfield(app.Tab, 'text');
            app.Inst_NameEditField.ValueChangedFcn = createCallbackFcn(app, @Inst_NameEditFieldValueChanged, true);
            app.Inst_NameEditField.Value = 'DAC name';
            app.numChanEditFieldLabel = uilabel(app.Tab);
            app.numChanEditFieldLabel.Text = 'numChan';
            app.numChanEditField = uieditfield(app.Tab, 'numeric');
            app.numChanEditField.ValueChangedFcn = createCallbackFcn(app, @numChanEditFieldValueChanged, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.SR830Label = uilabel(app.Tab2);
            app.SR830Label.Text = 'SR830';
            app.QueryInstrumentButton_5 = uibutton(app.Tab2, 'push');
            app.QueryInstrumentButton_5.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButton_5Pushed, true);
            app.QueryInstrumentButton_5.Text = 'Query Instrument';
            app.SetInstrumentButton_5 = uibutton(app.Tab2, 'push');
            app.SetInstrumentButton_5.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButton_5Pushed, true);
            app.SetInstrumentButton_5.Text = 'Set Instrument';
            app.SR830Label_2 = uilabel(app.Tab2);
            app.SR830Label_2.Text = 'SR830';
            app.QueryInstrumentButton_6 = uibutton(app.Tab2, 'push');
            app.QueryInstrumentButton_6.ButtonPushedFcn = createCallbackFcn(app, @QueryInstrumentButton_6Pushed, true);
            app.QueryInstrumentButton_6.Text = 'Query Instrument';
            app.SetInstrumentButton_6 = uibutton(app.Tab2, 'push');
            app.SetInstrumentButton_6.ButtonPushedFcn = createCallbackFcn(app, @SetInstrumentButton_6Pushed, true);
            app.SetInstrumentButton_6.Text = 'Set Instrument';
            app.InPhaseEditFieldLabel = uilabel(app.Tab2);
            app.InPhaseEditFieldLabel.Text = 'In-Phase';
            app.InPhaseEditField = uieditfield(app.Tab2, 'numeric');
            app.QuadratureEditFieldLabel = uilabel(app.Tab2);
            app.QuadratureEditFieldLabel.Text = 'Quadrature';
            app.QuadratureEditField = uieditfield(app.Tab2, 'numeric');
            app.VoltageVEditFieldLabel = uilabel(app.Tab2);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V)';
            app.VoltageVEditField = uieditfield(app.Tab2, 'numeric');
            app.SensitivityEditFieldLabel = uilabel(app.Tab2);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.Tab2, 'numeric');
            app.TimeConstantEditFieldLabel = uilabel(app.Tab2);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditField = uieditfield(app.Tab2, 'numeric');
            app.InstrNameEditField_5Label = uilabel(app.Tab2);
            app.InstrNameEditField_5Label.Text = 'Instr Name';
            app.InstrNameEditField_5 = uieditfield(app.Tab2, 'text');
            app.InstrNameEditField_5.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditField_5ValueChanged, true);
            app.Aux1EditFieldLabel = uilabel(app.Tab2);
            app.Aux1EditFieldLabel.Text = 'Aux 1';
            app.Aux1EditField = uieditfield(app.Tab2, 'numeric');
            app.Aux2EditFieldLabel = uilabel(app.Tab2);
            app.Aux2EditFieldLabel.Text = 'Aux 2';
            app.Aux2EditField = uieditfield(app.Tab2, 'numeric');
            app.Aux3EditFieldLabel = uilabel(app.Tab2);
            app.Aux3EditFieldLabel.Text = 'Aux 3';
            app.Aux3EditField = uieditfield(app.Tab2, 'numeric');
            app.Aux4EditFieldLabel = uilabel(app.Tab2);
            app.Aux4EditFieldLabel.Text = 'Aux 4';
            app.Aux4EditField = uieditfield(app.Tab2, 'numeric');
            app.FrequencyEditField_5Label = uilabel(app.Tab2);
            app.FrequencyEditField_5Label.Text = 'Frequency';
            app.FrequencyEditField_5 = uieditfield(app.Tab2, 'numeric');
            app.InPhaseEditField_2Label = uilabel(app.Tab2);
            app.InPhaseEditField_2Label.Text = 'In-Phase';
            app.InPhaseEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.QuadratureEditField_2Label = uilabel(app.Tab2);
            app.QuadratureEditField_2Label.Text = 'Quadrature';
            app.QuadratureEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.VoltageVEditField_2Label = uilabel(app.Tab2);
            app.VoltageVEditField_2Label.Text = 'Voltage (V)';
            app.VoltageVEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.SensitivityEditField_2Label = uilabel(app.Tab2);
            app.SensitivityEditField_2Label.Text = 'Sensitivity';
            app.SensitivityEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.TimeConstantEditField_2Label = uilabel(app.Tab2);
            app.TimeConstantEditField_2Label.Text = 'Time Constant';
            app.TimeConstantEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.InstrNameEditField_6Label = uilabel(app.Tab2);
            app.InstrNameEditField_6Label.Text = 'Instr Name';
            app.InstrNameEditField_6 = uieditfield(app.Tab2, 'text');
            app.InstrNameEditField_6.ValueChangedFcn = createCallbackFcn(app, @InstrNameEditField_6ValueChanged, true);
            app.Aux1EditField_2Label = uilabel(app.Tab2);
            app.Aux1EditField_2Label.Text = 'Aux 1';
            app.Aux1EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.Aux2EditField_2Label = uilabel(app.Tab2);
            app.Aux2EditField_2Label.Text = 'Aux 2';
            app.Aux2EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.Aux3EditField_2Label = uilabel(app.Tab2);
            app.Aux3EditField_2Label.Text = 'Aux 3';
            app.Aux3EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.Aux4EditField_2Label = uilabel(app.Tab2);
            app.Aux4EditField_2Label.Text = 'Aux 4';
            app.Aux4EditField_2 = uieditfield(app.Tab2, 'numeric');
            app.FrequencyEditField_6Label = uilabel(app.Tab2);
            app.FrequencyEditField_6Label.Text = 'Frequency';
            app.FrequencyEditField_6 = uieditfield(app.Tab2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tiffany_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end