classdef sigDACGUI_Tiff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        numChanEditField         matlab.ui.control.NumericEditField
        numChanEditFieldLabel    matlab.ui.control.Label
        Inst_NameEditField       matlab.ui.control.EditField
        Inst_NameEditFieldLabel  matlab.ui.control.Label
        EditField_24             matlab.ui.control.EditField
        EditField_23             matlab.ui.control.EditField
        EditField_22             matlab.ui.control.EditField
        EditField_21             matlab.ui.control.EditField
        EditField_20             matlab.ui.control.EditField
        EditField_19             matlab.ui.control.EditField
        EditField_18             matlab.ui.control.EditField
        EditField_17             matlab.ui.control.EditField
        EditField_16             matlab.ui.control.EditField
        EditField_15             matlab.ui.control.EditField
        EditField_14             matlab.ui.control.EditField
        EditField_13             matlab.ui.control.EditField
        EditField_12             matlab.ui.control.EditField
        EditField_11             matlab.ui.control.EditField
        EditField_10             matlab.ui.control.EditField
        EditField_9              matlab.ui.control.EditField
        EditField_8              matlab.ui.control.EditField
        EditField_7              matlab.ui.control.EditField
        EditField_6              matlab.ui.control.EditField
        EditField_5              matlab.ui.control.EditField
        EditField_4              matlab.ui.control.EditField
        EditField_3              matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EditField                matlab.ui.control.EditField
        Update1724Button         matlab.ui.control.Button
        Update916Button          matlab.ui.control.Button
        Update18Button           matlab.ui.control.Button
        UpdateAllButton          matlab.ui.control.Button
        sigDACLabel              matlab.ui.control.Label
        c24EditField             matlab.ui.control.NumericEditField
        c23EditField             matlab.ui.control.NumericEditField
        c22EditField             matlab.ui.control.NumericEditField
        c21EditField             matlab.ui.control.NumericEditField
        c20EditField             matlab.ui.control.NumericEditField
        c19EditField             matlab.ui.control.NumericEditField
        c18EditField             matlab.ui.control.NumericEditField
        c17EditField             matlab.ui.control.NumericEditField
        c16EditField             matlab.ui.control.NumericEditField
        c15EditField             matlab.ui.control.NumericEditField
        c14EditField             matlab.ui.control.NumericEditField
        c13EditField             matlab.ui.control.NumericEditField
        c12EditField             matlab.ui.control.NumericEditField
        c11EditField             matlab.ui.control.NumericEditField
        c10EditField             matlab.ui.control.NumericEditField
        c9EditField              matlab.ui.control.NumericEditField
        c8EditField              matlab.ui.control.NumericEditField
        c7EditField              matlab.ui.control.NumericEditField
        c6EditField              matlab.ui.control.NumericEditField
        c5EditField              matlab.ui.control.NumericEditField
        c4EditField              matlab.ui.control.NumericEditField
        c3EditField              matlab.ui.control.NumericEditField
        c2EditField              matlab.ui.control.NumericEditField
        c1EditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        instrumentName = ''; 
        numChannels = 8;
    end
    
    methods (Access = private)
        function fieldArr = getEditFields(app)
            fieldArr = [app.c1EditField,app.c2EditField,app.c3EditField,app.c4EditField,app.c5EditField,app.c6EditField,app.c7EditField,app.c8EditField,app.c9EditField,app.c10EditField,app.c11EditField,app.c12EditField,app.c13EditField,app.c14EditField,app.c15EditField,app.c16EditField,app.c17EditField,app.c18EditField,app.c19EditField,app.c20EditField,app.c21EditField,app.c22EditField,app.c23EditField,app.c24EditField]; % Description
        end

        function [] = updateEditField(app,channel,val)
            fieldArr = getEditFields(app);
            numChans = app.numChannels;
            if channel <= numChans 
                fieldArr(channel).Value = val;
            else
                disp('You do not have that many channels!');
            end
        end

        function queryDAC(app,channel)

            voltage = evalin('base',['sigDACQueryVoltage(' app.instrumentName ',' num2str(channel) ');']);
            updateEditField(app,channel,voltage);

        end
    end
    
    methods (Access = public)

        function [] = updateDACGUIChan(app,channel)
            queryDAC(app,channel);
        end

        function updateDACGUI(app)
        
            for i = 1:app.numChannels
                updateDACGUIChan(app,i);
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)
            for i=1:app.numChannels
                queryDAC(app,i);
            end
        end

        % Button pushed function: Update18Button
        function Update18ButtonPushed(app, event)
            app.numChannels;
            if app.numChannels < 1
                disp('You do not have that many channels!');
            else
                for i=1:8
                    queryDAC(app,i);
                end
            end
            
        end

        % Button pushed function: Update916Button
        function Update916ButtonPushed(app, event)
            if app.numChannels < 9
                disp('You do not have that many channels!');
                return
            else
                for i=9:16
                    queryDAC(app,i);
                end
            end
            
        end

        % Button pushed function: Update1724Button
        function Update1724ButtonPushed(app, event)
            if app.numChannels < 17
                disp('You do not have that many channels!');
            else
                for i=17:24
                    queryDAC(app,i);
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = questdlg('Close the DAC GUI?',...
                'Close Request Function',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    delete(app);
                case 'No'
                    return
            end
            
        end

        % Value changed function: Inst_NameEditField
        function Inst_NameEditFieldValueChanged(app, event)
            value = app.Inst_NameEditField.Value;
            app.instrumentName = value;
        end

        % Value changed function: numChanEditField
        function numChanEditFieldValueChanged(app, event)
            app.numChannels = app.numChanEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.c1EditField = uieditfield(app.UIFigure, 'numeric');
            app.c2EditField = uieditfield(app.UIFigure, 'numeric');
            app.c3EditField = uieditfield(app.UIFigure, 'numeric');
            app.c4EditField = uieditfield(app.UIFigure, 'numeric');
            app.c5EditField = uieditfield(app.UIFigure, 'numeric');
            app.c6EditField = uieditfield(app.UIFigure, 'numeric');
            app.c7EditField = uieditfield(app.UIFigure, 'numeric');
            app.c8EditField = uieditfield(app.UIFigure, 'numeric');
            app.c9EditField = uieditfield(app.UIFigure, 'numeric');
            app.c10EditField = uieditfield(app.UIFigure, 'numeric');
            app.c11EditField = uieditfield(app.UIFigure, 'numeric');
            app.c12EditField = uieditfield(app.UIFigure, 'numeric');
            app.c13EditField = uieditfield(app.UIFigure, 'numeric');
            app.c14EditField = uieditfield(app.UIFigure, 'numeric');
            app.c15EditField = uieditfield(app.UIFigure, 'numeric');
            app.c16EditField = uieditfield(app.UIFigure, 'numeric');
            app.c17EditField = uieditfield(app.UIFigure, 'numeric');
            app.c18EditField = uieditfield(app.UIFigure, 'numeric');
            app.c19EditField = uieditfield(app.UIFigure, 'numeric');
            app.c20EditField = uieditfield(app.UIFigure, 'numeric');
            app.c21EditField = uieditfield(app.UIFigure, 'numeric');
            app.c22EditField = uieditfield(app.UIFigure, 'numeric');
            app.c23EditField = uieditfield(app.UIFigure, 'numeric');
            app.c24EditField = uieditfield(app.UIFigure, 'numeric');
            app.sigDACLabel = uilabel(app.UIFigure);
            app.sigDACLabel.Text = 'sigDAC';
            app.UpdateAllButton = uibutton(app.UIFigure, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Text = 'Update All';
            app.Update18Button = uibutton(app.UIFigure, 'push');
            app.Update18Button.ButtonPushedFcn = createCallbackFcn(app, @Update18ButtonPushed, true);
            app.Update18Button.Text = 'Update 1-8';
            app.Update916Button = uibutton(app.UIFigure, 'push');
            app.Update916Button.ButtonPushedFcn = createCallbackFcn(app, @Update916ButtonPushed, true);
            app.Update916Button.Text = 'Update 9-16';
            app.Update1724Button = uibutton(app.UIFigure, 'push');
            app.Update1724Button.ButtonPushedFcn = createCallbackFcn(app, @Update1724ButtonPushed, true);
            app.Update1724Button.Text = 'Update 17-24';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'STI C';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'TF Emitter';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'STI Emitter';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'TF Collector';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = 'NA';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Value = 'Top Fil Metal';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Value = 'IDC Neg --';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.Value = 'Twiddle C';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_9.Value = 'Door In C';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_10.Value = 'Door In Emit';
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_11.Value = 'IDC Pos +';
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_12.Value = 'Sense C';
            app.EditField_13 = uieditfield(app.UIFigure, 'text');
            app.EditField_13.Value = 'Top E';
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.EditField_14.Value = 'Door C Open';
            app.EditField_15 = uieditfield(app.UIFigure, 'text');
            app.EditField_15.Value = 'Sense E';
            app.EditField_16 = uieditfield(app.UIFigure, 'text');
            app.EditField_16.Value = 'Door E Open';
            app.EditField_17 = uieditfield(app.UIFigure, 'text');
            app.EditField_17.Value = 'Top C';
            app.EditField_18 = uieditfield(app.UIFigure, 'text');
            app.EditField_18.Value = 'Twiddle E';
            app.EditField_19 = uieditfield(app.UIFigure, 'text');
            app.EditField_19.Value = 'STM C';
            app.EditField_20 = uieditfield(app.UIFigure, 'text');
            app.EditField_20.Value = 'STM E';
            app.EditField_21 = uieditfield(app.UIFigure, 'text');
            app.EditField_21.Value = 'STO C';
            app.EditField_22 = uieditfield(app.UIFigure, 'text');
            app.EditField_22.Value = 'Door C Close';
            app.EditField_23 = uieditfield(app.UIFigure, 'text');
            app.EditField_23.Value = 'STO E';
            app.EditField_24 = uieditfield(app.UIFigure, 'text');
            app.EditField_24.Value = 'Door E Close';
            app.Inst_NameEditFieldLabel = uilabel(app.UIFigure);
            app.Inst_NameEditFieldLabel.Text = 'Inst_Name';
            app.Inst_NameEditField = uieditfield(app.UIFigure, 'text');
            app.Inst_NameEditField.ValueChangedFcn = createCallbackFcn(app, @Inst_NameEditFieldValueChanged, true);
            app.Inst_NameEditField.Value = 'DAC name';
            app.numChanEditFieldLabel = uilabel(app.UIFigure);
            app.numChanEditFieldLabel.Text = 'numChan';
            app.numChanEditField = uieditfield(app.UIFigure, 'numeric');
            app.numChanEditField.ValueChangedFcn = createCallbackFcn(app, @numChanEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigDACGUI_Tiff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end