classdef sweepSR830GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        numRepeatsEditField        matlab.ui.control.NumericEditField
        numRepeatsEditFieldLabel   matlab.ui.control.Label
        UpdateParamsButton         matlab.ui.control.Button
        PortNumberEditField        matlab.ui.control.NumericEditField
        PortNumberEditFieldLabel   matlab.ui.control.Label
        WaitTimeEditField          matlab.ui.control.NumericEditField
        WaitTimeEditFieldLabel     matlab.ui.control.Label
        SweepDeviceEditField       matlab.ui.control.EditField
        SweepDeviceEditFieldLabel  matlab.ui.control.Label
        SR830ReadEditField         matlab.ui.control.EditField
        SR830ReadEditFieldLabel    matlab.ui.control.Label
        SweepButton                matlab.ui.control.Button
        DeltaValEditField          matlab.ui.control.NumericEditField
        DeltaValEditFieldLabel     matlab.ui.control.Label
        StopValEditField           matlab.ui.control.NumericEditField
        StopValEditFieldLabel      matlab.ui.control.Label
        StartValEditField          matlab.ui.control.NumericEditField
        StartValEditFieldLabel     matlab.ui.control.Label
        SweepTypeEditField         matlab.ui.control.EditField
        SweepTypeEditFieldLabel    matlab.ui.control.Label
        doBackAndForthCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        sweepTypeCell = {};
        SR830Cell = {};
        doBackAndForth = 0;
    end
    
    methods (Access = private)
        
        function getSweepTypeGateVal(app,sweepType)
            sweepType = convertStringsToChars(sweepType{1});
        switch sweepType
            case 'ST'
                app.PortNumberEditField.Value = 8;
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',8)']);
            case 'TM'
                app.PortNumberEditField.Value = 4;
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',4)']);
            case 'Res'
                app.PortNumberEditField.Value = 3;
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',3)']);
            case 'Door'
                app.PortNumberEditField.Value = 1;
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',1)']);
            case 'DP'
                app.PortNumberEditField.Value = 2;
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',2)']);
            case 'Pair'
                 app.PortNumberEditField.Value = 4;
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',4);']);
            case 'Freq'
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',' char(39) 'Freq' char(39) ');']);
            case 'Amp'
                startVal = evalin('base',['getVal(' app.SweepDeviceEditField.Value ',' char(39) 'Amp' char(39) ');']);
            otherwise
                disp("Sweep Type undefined, include it in the GUI!");
        end
        app.StartValEditField.Value = startVal;
        end
        
        function updateSweepParams(app)
            app.getSweepTypeGateVal(app.sweepTypeCell{1});
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SweepButton
        function SweepButtonPushed(app, event)
            app.updateSweepParams();
            disp(app.StartValEditField.Value);
            disp(app.sweepTypeCell{1})
            sweep1DMeasSR830(app.sweepTypeCell{1},app.StartValEditField.Value,app.StopValEditField.Value,app.DeltaValEditField.Value,app.WaitTimeEditField.Value,app.numRepeatsEditField.Value,app.SR830Cell,app.SweepDeviceEditField.Value,app.PortNumberEditField.Value,app.doBackAndForth.Value);
        end

        % Value changed function: SweepTypeEditField
        function SweepTypeEditFieldValueChanged(app, event)
            sweepTypeArr = split(app.SweepTypeEditField.Value,",");
            app.sweepTypeCell = num2cell(sweepTypeArr);
            app.getSweepTypeGateVal(app.sweepTypeCell{1});
        end

        % Value changed function: SR830ReadEditField
        function SR830ReadEditFieldValueChanged(app, event)
            SR830Arr = split(app.SR830ReadEditField.Value,",");
            app.SR830Cell = num2cell(SR830Arr);
        end

        % Value changed function: doBackAndForthCheckBox
        function doBackAndForthCheckBoxValueChanged(app, event)
            app.doBackAndForth = app.doBackAndForthCheckBox.Value;
        end

        % Button pushed function: UpdateParamsButton
        function UpdateParamsButtonPushed(app, event)
            app.updateSweepParams();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.doBackAndForthCheckBox = uicheckbox(app.UIFigure);
            app.doBackAndForthCheckBox.ValueChangedFcn = createCallbackFcn(app, @doBackAndForthCheckBoxValueChanged, true);
            app.doBackAndForthCheckBox.Text = 'doBackAndForth?';
            app.SweepTypeEditFieldLabel = uilabel(app.UIFigure);
            app.SweepTypeEditFieldLabel.Text = 'Sweep Type';
            app.SweepTypeEditField = uieditfield(app.UIFigure, 'text');
            app.SweepTypeEditField.ValueChangedFcn = createCallbackFcn(app, @SweepTypeEditFieldValueChanged, true);
            app.StartValEditFieldLabel = uilabel(app.UIFigure);
            app.StartValEditFieldLabel.Text = 'StartVal';
            app.StartValEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopValEditFieldLabel = uilabel(app.UIFigure);
            app.StopValEditFieldLabel.Text = 'StopVal';
            app.StopValEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaValEditFieldLabel = uilabel(app.UIFigure);
            app.DeltaValEditFieldLabel.Text = 'DeltaVal';
            app.DeltaValEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepButton = uibutton(app.UIFigure, 'push');
            app.SweepButton.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed, true);
            app.SweepButton.Text = 'Sweep';
            app.SR830ReadEditFieldLabel = uilabel(app.UIFigure);
            app.SR830ReadEditFieldLabel.Text = 'SR830 Read';
            app.SR830ReadEditField = uieditfield(app.UIFigure, 'text');
            app.SR830ReadEditField.ValueChangedFcn = createCallbackFcn(app, @SR830ReadEditFieldValueChanged, true);
            app.SweepDeviceEditFieldLabel = uilabel(app.UIFigure);
            app.SweepDeviceEditFieldLabel.Text = 'Sweep Device';
            app.SweepDeviceEditField = uieditfield(app.UIFigure, 'text');
            app.WaitTimeEditFieldLabel = uilabel(app.UIFigure);
            app.WaitTimeEditFieldLabel.Text = 'Wait Time';
            app.WaitTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateParamsButton = uibutton(app.UIFigure, 'push');
            app.UpdateParamsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParamsButtonPushed, true);
            app.UpdateParamsButton.Text = 'Update Params';
            app.numRepeatsEditFieldLabel = uilabel(app.UIFigure);
            app.numRepeatsEditFieldLabel.Text = 'numRepeats';
            app.numRepeatsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sweepSR830GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end